classdef udp_cmd_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        rf                    matlab.ui.container.Tab
        READYButton_RF        matlab.ui.control.Button
        AM_Param_RF           matlab.ui.control.NumericEditField
        AMLabel               matlab.ui.control.Label
        FM_Freq_RF            matlab.ui.control.NumericEditField
        FMKHzEditFieldLabel   matlab.ui.control.Label
        NFM_FMT_RF            matlab.ui.control.NumericEditField
        NFMusEditFieldLabel   matlab.ui.control.Label
        NFM_FMB_RF            matlab.ui.control.NumericEditField
        NFMKHzEditFieldLabel  matlab.ui.control.Label
        LFM_FMN_RF            matlab.ui.control.NumericEditField
        LFMusEditFieldLabel   matlab.ui.control.Label
        LFM_FMC_RF            matlab.ui.control.NumericEditField
        LFMLabel              matlab.ui.control.Label
        LFM_FMB_RF            matlab.ui.control.NumericEditField
        LFMKHzEditFieldLabel  matlab.ui.control.Label
        PlusePeriod_RF        matlab.ui.control.NumericEditField
        usEditField_2Label    matlab.ui.control.Label
        PluseWidth_RF         matlab.ui.control.NumericEditField
        usEditFieldLabel      matlab.ui.control.Label
        AttValue_RF           matlab.ui.control.NumericEditField
        dbEditFieldLabel      matlab.ui.control.Label
        FreqValue_RF          matlab.ui.control.NumericEditField
        Label_4               matlab.ui.control.Label
        ChannelSw_RF          matlab.ui.control.DropDown
        Label_3               matlab.ui.control.Label
        SignalStyle_RF        matlab.ui.control.DropDown
        Label_2               matlab.ui.control.Label
        ChannelSet_RF         matlab.ui.control.DropDown
        Label                 matlab.ui.control.Label
        ttl                   matlab.ui.container.Tab
        TTLPanel              matlab.ui.container.Panel
        READYButton_TTL       matlab.ui.control.Button
        TTLPeriod             matlab.ui.control.NumericEditField
        usLabel_4             matlab.ui.control.Label
        TTLPW                 matlab.ui.control.NumericEditField
        usLabel_3             matlab.ui.control.Label
        TTLHL                 matlab.ui.control.NumericEditField
        TTLEditFieldLabel     matlab.ui.control.Label
        ChannelSw_TTL         matlab.ui.control.DropDown
        DropDown_9Label       matlab.ui.control.Label
        SignalStyle_TTL       matlab.ui.control.DropDown
        DropDown_8Label       matlab.ui.control.Label
        ChannelSet_TTL        matlab.ui.control.DropDown
        DropDown_7Label       matlab.ui.control.Label
        RadarPanel            matlab.ui.container.Panel
        READYButton_Radar     matlab.ui.control.Button
        DelayTime_Radar       matlab.ui.control.NumericEditField
        usLabel_2             matlab.ui.control.Label
        PW_Radar              matlab.ui.control.NumericEditField
        usLabel               matlab.ui.control.Label
        ChannelSw_Radar       matlab.ui.control.DropDown
        DropDown_6Label       matlab.ui.control.Label
        SignalStyle_Radar     matlab.ui.control.DropDown
        DropDown_5Label       matlab.ui.control.Label
        ChannelSet_Radar      matlab.ui.control.DropDown
        DropDown_4Label       matlab.ui.control.Label
        power                 matlab.ui.container.Tab
        ChannelSet_Power      matlab.ui.control.DropDown
        DropDown_4Label_2     matlab.ui.control.Label
        READYButton_Power     matlab.ui.control.Button
        Switch_XS21           matlab.ui.control.Switch
        Current_Power         matlab.ui.control.NumericEditField
        AEditFieldLabel       matlab.ui.control.Label
        Voltage_Power         matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        led                   matlab.ui.container.Tab
        READYButton_LED       matlab.ui.control.Button
        XS24_LED              matlab.ui.control.CheckBox
        XS23_LED              matlab.ui.control.CheckBox
        XS22_LED              matlab.ui.control.CheckBox
        XS21_LED              matlab.ui.control.CheckBox
        XS20_LED              matlab.ui.control.CheckBox
        XS19_LED              matlab.ui.control.CheckBox
        XS18_LED              matlab.ui.control.CheckBox
        XS17_LED              matlab.ui.control.CheckBox
        XS16_LED              matlab.ui.control.CheckBox
        XS15_LED              matlab.ui.control.CheckBox
        XS14_LED              matlab.ui.control.CheckBox
        XS13_LED              matlab.ui.control.CheckBox
        XS12_LED              matlab.ui.control.CheckBox
        XS11_LED              matlab.ui.control.CheckBox
        XS10_LED              matlab.ui.control.CheckBox
        XS09_LED              matlab.ui.control.CheckBox
        XS08_LED              matlab.ui.control.CheckBox
        XS07_LED              matlab.ui.control.CheckBox
        XS06_LED              matlab.ui.control.CheckBox
        XS05_LED              matlab.ui.control.CheckBox
        XS04_LED              matlab.ui.control.CheckBox
        XS03_LED              matlab.ui.control.CheckBox
        XS02_LED              matlab.ui.control.CheckBox
        XS01_LED              matlab.ui.control.CheckBox
        UDPTab                matlab.ui.container.Tab
        Bind_Button           matlab.ui.control.StateButton
        DIS_Port              matlab.ui.control.NumericEditField
        Label_9               matlab.ui.control.Label
        LOC_Port              matlab.ui.control.NumericEditField
        Label_8               matlab.ui.control.Label
        UDP_TESTButton        matlab.ui.control.Button
        DIS_IP                matlab.ui.control.EditField
        IPLabel               matlab.ui.control.Label
        LOC_IP                matlab.ui.control.EditField
        IPIPEditField_2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        loc_ip     
        loc_port
        dis_ip
        dis_port
        udpportobj
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UDP_TESTButton
        function UDP_TESTButtonPushed(app, event)

            testsendbuf = [0x01,0x02,0x03,0x04,0x05,0x06];
            write(app.udpportobj,testsendbuf,"uint8",app.dis_ip,app.dis_port);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear app.udpportobj
        end

        % Button pushed function: READYButton_RF
        function READYButton_RFPushed(app, event)
            RFsendbuf =[0xFE,0xEF,0x33,0x00,0x00,0x00,0x00,0x01,0xFF];
            switch app.ChannelSet_RF.Value
                case "XS01"
                    RFsendbuf(5)=0x01;
                case "XS02"
                    RFsendbuf(5)=0x02;
                case "XS03"
                    RFsendbuf(5)=0x03;
                case "XS04"
                    RFsendbuf(5)=0x04;
                case "XS05"
                    RFsendbuf(5)=0x05;
                case "XS06"
                    RFsendbuf(5)=0x06;
                case "XS07"
                    RFsendbuf(5)=0x07;
                case "XS08"
                    RFsendbuf(5)=0x08;
                case "XS09"
                    RFsendbuf(5)=0x09;
                case "XS10"
                    RFsendbuf(5)=0x0A;
                case "XS11"
                    RFsendbuf(5)=0x0B;
                case "XS12"
                    RFsendbuf(5)=0x0C;
                otherwise 
             end
             switch app.SignalStyle_RF.Value
                 case "连续波(LO)"
                     databuf(1)=0x00;
                 case "脉冲调幅(PAM)"
                     databuf(1)=0x01;
                 case "线性调频(LFM)"
                     databuf(1)=0x02;
                 case "噪声调频(NFM)"
                     databuf(1)=0x03;
                 case "FM"
                     databuf(1)=0x04;
                 case "AM"
                     databuf(1)=0x05;
                 otherwise
             end
             switch app.ChannelSw_RF.Value
                 case "开"
                     databuf(2)=0x01;
                 case "关"
                     databuf(2)=0x00;
                 otherwise
             end
             databuf(3:10)  = typecast(uint64(app.FreqValue_RF.Value),'uint8');
             databuf(11)    = typecast(uint8(app.AttValue_RF.Value),'uint8');
             databuf(12:15) = typecast(uint32(app.PluseWidth_RF.Value),'uint8');
             databuf(16:19) = typecast(uint32(app.PlusePeriod_RF.Value),'uint8');
             databuf(20:23) = typecast(uint32(app.LFM_FMB_RF.Value),'uint8');
             databuf(24:27) = typecast(uint32(app.LFM_FMC_RF.Value),'uint8');
             databuf(28:29) = typecast(uint16(app.LFM_FMN_RF.Value),'uint8');
             databuf(30:33) = typecast(uint32(app.NFM_FMB_RF.Value),'uint8');
             databuf(34:37) = typecast(uint32(app.NFM_FMT_RF.Value),'uint8');
             databuf(38:41) = typecast(uint32(app.FM_Freq_RF.Value),'uint8');
             databuf(42)    = typecast(uint8(app.AM_Param_RF.Value),'uint8');

             RFsendbuf = [RFsendbuf(1:8),databuf,RFsendbuf(9)];
             fprintf('0x%02X ', RFsendbuf);
             fprintf('\n');
             write(app.udpportobj,RFsendbuf,"uint8",app.dis_ip,app.dis_port);

        end

        % Button pushed function: READYButton_Radar
        function READYButton_RadarPushed(app, event)
            Radarsendbuf =[0xFE,0xEF,0x13,0x00,0x00,0x01,0x00,0x01,0xFF];
            switch app.ChannelSet_Radar.Value
                 case "XS13"
                     Radarsendbuf(5)=0x01;
                 case "XS14"
                     Radarsendbuf(5)=0x02;
                 case "XS15"
                     Radarsendbuf(5)=0x03;
                 case "XS16"
                     Radarsendbuf(5)=0x04;
                 otherwise
            end
            switch app.SignalStyle_Radar.Value
                 case "雷达脉冲信号"
                     databuf(1)=0x01;
                 case "雷达导前信号"
                     databuf(1)=0x02;
                 otherwise
             end
             switch app.ChannelSw_Radar.Value
                 case "开"
                     databuf(2)=0x01;
                 case "关"
                     databuf(2)=0x00;
                 otherwise
             end
             databuf(3:6)  = typecast(uint32(app.PW_Radar.Value),'uint8');
             databuf(7:10)  = typecast(uint32(app.DelayTime_Radar.Value),'uint8');
             Radarsendbuf = [Radarsendbuf(1:8),databuf,Radarsendbuf(9)];
             fprintf('0x%02X ', Radarsendbuf);
             fprintf('\n');
             write(app.udpportobj,Radarsendbuf,"uint8",app.dis_ip,app.dis_port);
        end

        % Button pushed function: READYButton_TTL
        function READYButton_TTLPushed(app, event)
            TTLsendbuf =[0xFE,0xEF,0x14,0x00,0x00,0x01,0x00,0x01,0xFF];
            switch app.ChannelSet_TTL.Value
                 case "XS17"
                     TTLsendbuf(5)=0x05;
                 case "XS18"
                     TTLsendbuf(5)=0x06;
                 case "XS19"
                     TTLsendbuf(5)=0x07;
                 case "XS20"
                     TTLsendbuf(5)=0x08;
                 otherwise
            end
            switch app.SignalStyle_TTL.Value
                 case "TTL高低电平"
                     databuf(1)=0x02;
                 case "时序脉冲"
                     databuf(1)=0x03;
                 otherwise
             end
             switch app.ChannelSw_TTL.Value
                 case "开"
                     databuf(2)=0x01;
                 case "关"
                     databuf(2)=0x00;
                 otherwise
             end
             databuf(3)    = typecast(uint8(app.TTLHL.Value),'uint8');
             databuf(4:7)  = typecast(uint32(app.TTLPW.Value),'uint8');
             databuf(8:11)  = typecast(uint32(app.TTLPeriod.Value),'uint8');
             TTLsendbuf = [TTLsendbuf(1:8),databuf,TTLsendbuf(9)];
             fprintf('0x%02X ', TTLsendbuf);
             fprintf('\n');
             write(app.udpportobj,TTLsendbuf,"uint8",app.dis_ip,app.dis_port);
        end

        % Button pushed function: READYButton_Power
        function READYButton_PowerPushed(app, event)
            Powersendbuf =[0xFE,0xEF,0x0C,0x00,0x00,0x02,0x00,0x01,0xFF];
            switch app.ChannelSet_Power.Value
                 case "XS21"
                     Powersendbuf(5)=0x01;
                 case "XS22"
                     Powersendbuf(5)=0x02;
                 case "XS23"
                     Powersendbuf(5)=0x03;
                 case "XS24"
                     Powersendbuf(5)=0x04;
                 otherwise
            end

            if app.Switch_XS21.Value   sw = 1; else sw = 0; end

            databuf(1)    = typecast(uint8(app.Voltage_Power.Value),'uint8');
            databuf(2)    = typecast(uint8(app.Current_Power.Value),'uint8');
            databuf(3)    = typecast(uint8(sw),  'uint8');
            Powersendbuf  = [Powersendbuf(1:8),databuf,Powersendbuf(9)];
            fprintf('0x%02X ', Powersendbuf);
            fprintf('\n');
            write(app.udpportobj,Powersendbuf,"uint8",app.dis_ip,app.dis_port);
        end

        % Button pushed function: READYButton_LED
        function READYButton_LEDPushed(app, event)
            LEDsendbuf =[0xFE,0xEF,0x0D,0x00,0x01,0x03,0x00,0x01,0xFF];
            % uint32 LED =0;
            if app.XS01_LED.Value   led1 = 1; else led1 = 0; end
            if app.XS02_LED.Value   led2 = 1; else led2 = 0; end
            if app.XS03_LED.Value   led3 = 1; else led3 = 0; end
            if app.XS04_LED.Value   led4 = 1; else led4 = 0; end
            if app.XS05_LED.Value   led5 = 1; else led5 = 0; end
            if app.XS06_LED.Value   led6 = 1; else led6 = 0; end
            if app.XS07_LED.Value   led7 = 1; else led7 = 0; end
            if app.XS08_LED.Value   led8 = 1; else led8 = 0; end
            if app.XS09_LED.Value   led9 = 1; else led9 = 0; end
            if app.XS10_LED.Value   led10 = 1; else led10 = 0; end
            if app.XS11_LED.Value   led11 = 1; else led11 = 0; end
            if app.XS12_LED.Value   led12 = 1; else led12 = 0; end
            if app.XS13_LED.Value   led13 = 1; else led13 = 0; end
            if app.XS14_LED.Value   led14 = 1; else led14 = 0; end
            if app.XS15_LED.Value   led15 = 1; else led15 = 0; end
            if app.XS16_LED.Value   led16 = 1; else led16 = 0; end
            if app.XS17_LED.Value   led17 = 1; else led17 = 0; end
            if app.XS18_LED.Value   led18 = 1; else led18 = 0; end
            if app.XS19_LED.Value   led19 = 1; else led19 = 0; end
            if app.XS20_LED.Value   led20 = 1; else led20 = 0; end
            if app.XS21_LED.Value   led21 = 1; else led21 = 0; end
            if app.XS22_LED.Value   led22 = 1; else led22 = 0; end
            if app.XS23_LED.Value   led23 = 1; else led23 = 0; end
            if app.XS24_LED.Value   led24 = 1; else led24 = 0; end


            LED =     bitshift(led1,0)+...
                      bitshift(led2,1)+...
                      bitshift(led3,2)+...
                      bitshift(led4,3)+...
                      bitshift(led5,4)+...
                      bitshift(led6,5)+...
                      bitshift(led7,6)+...
                      bitshift(led8,7)+...
                      bitshift(led9,8)+...
                      bitshift(led10,9)+...
                      bitshift(led11,10)+...
                      bitshift(led12,11)+...
                      bitshift(led13,12)+...
                      bitshift(led14,13)+...
                      bitshift(led15,14)+...
                      bitshift(led16,15)+...
                      bitshift(led17,16)+...
                      bitshift(led18,17)+...
                      bitshift(led19,18)+...
                      bitshift(led20,19)+...
                      bitshift(led21,20)+...
                      bitshift(led22,21)+...
                      bitshift(led23,22)+...
                      bitshift(led24,23);
            databuf(1:4)    = typecast(uint32(LED),  'uint8');
            LEDsendbuf      = [LEDsendbuf(1:8),databuf,LEDsendbuf(9)];
            fprintf('0x%02X ', LEDsendbuf);
            fprintf('\n');
            write(app.udpportobj,LEDsendbuf,"uint8",app.dis_ip,app.dis_port);
        end

        % Value changed function: Bind_Button
        function Bind_ButtonValueChanged(app, event)
            value = app.Bind_Button.Value;
            if value
            app.loc_ip      = app.LOC_IP.Value  ;
            app.loc_port    = app.LOC_Port.Value;
            app.dis_ip      = app.DIS_IP.Value  ;
            app.dis_port    = app.DIS_Port.Value;
            app.udpportobj  = udpport("LocalHost",app.loc_ip,"LocalPort",app.loc_port);

            app.Bind_Button.Text = "解绑";
            app.Bind_Button.BackgroundColor = [0.56,0.96,0.56];
            else 
                clear app.udpportobj;
                app.Bind_Button.Text = "绑定";
                app.Bind_Button.BackgroundColor = [0.96,0.96,0.96];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.rf = uitab(app.TabGroup);
            app.rf.Title = '射频类';
            app.Label = uilabel(app.rf);
            app.Label.Text = '通道设置';
            app.ChannelSet_RF = uidropdown(app.rf);
            app.ChannelSet_RF.Items = {'XS01', 'XS02', 'XS03', 'XS04', 'XS05', 'XS06', 'XS07', 'XS08', 'XS09', 'XS10', 'XS11', 'XS12'};
            app.ChannelSet_RF.Value = 'XS01';
            app.Label_2 = uilabel(app.rf);
            app.Label_2.Text = '信号样式';
            app.SignalStyle_RF = uidropdown(app.rf);
            app.SignalStyle_RF.Items = {'连续波(LO)', '脉冲调幅(PAM)', '线性调频(LFM)', '噪声调频(NFM)', 'FM', 'AM'};
            app.SignalStyle_RF.Value = '连续波(LO)';
            app.Label_3 = uilabel(app.rf);
            app.Label_3.Text = '通道开关';
            app.ChannelSw_RF = uidropdown(app.rf);
            app.ChannelSw_RF.Items = {'开', '关'};
            app.ChannelSw_RF.Value = '开';
            app.Label_4 = uilabel(app.rf);
            app.Label_4.Text = '频率值/Hz';
            app.FreqValue_RF = uieditfield(app.rf, 'numeric');
            app.FreqValue_RF.Limits = [-Inf 6000000000];
            app.FreqValue_RF.Value = 80000000;
            app.dbEditFieldLabel = uilabel(app.rf);
            app.dbEditFieldLabel.Text = '衰减值/db';
            app.AttValue_RF = uieditfield(app.rf, 'numeric');
            app.AttValue_RF.Value = 10;
            app.usEditFieldLabel = uilabel(app.rf);
            app.usEditFieldLabel.Text = '脉宽/us';
            app.PluseWidth_RF = uieditfield(app.rf, 'numeric');
            app.PluseWidth_RF.ValueDisplayFormat = '%.0f';
            app.PluseWidth_RF.Value = 50;
            app.usEditField_2Label = uilabel(app.rf);
            app.usEditField_2Label.Text = '重周/us';
            app.PlusePeriod_RF = uieditfield(app.rf, 'numeric');
            app.PlusePeriod_RF.ValueDisplayFormat = '%.0f';
            app.PlusePeriod_RF.Value = 100;
            app.LFMKHzEditFieldLabel = uilabel(app.rf);
            app.LFMKHzEditFieldLabel.Text = 'LFM调频带宽/KHz';
            app.LFM_FMB_RF = uieditfield(app.rf, 'numeric');
            app.LFM_FMB_RF.ValueDisplayFormat = '%.0f';
            app.LFM_FMB_RF.Value = 5000;
            app.LFMLabel = uilabel(app.rf);
            app.LFMLabel.Text = 'LFM调频点数/个';
            app.LFM_FMC_RF = uieditfield(app.rf, 'numeric');
            app.LFM_FMC_RF.ValueDisplayFormat = '%.0f';
            app.LFM_FMC_RF.Value = 10;
            app.LFMusEditFieldLabel = uilabel(app.rf);
            app.LFMusEditFieldLabel.Text = 'LFM调频周期/us';
            app.LFM_FMN_RF = uieditfield(app.rf, 'numeric');
            app.LFM_FMN_RF.ValueDisplayFormat = '%.0f';
            app.LFM_FMN_RF.Value = 10000;
            app.NFMKHzEditFieldLabel = uilabel(app.rf);
            app.NFMKHzEditFieldLabel.Text = 'NFM噪声带宽/KHz';
            app.NFM_FMB_RF = uieditfield(app.rf, 'numeric');
            app.NFM_FMB_RF.ValueDisplayFormat = '%.0f';
            app.NFM_FMB_RF.Value = 5000;
            app.NFMusEditFieldLabel = uilabel(app.rf);
            app.NFMusEditFieldLabel.Text = 'NFM调频时间间隔/us';
            app.NFM_FMT_RF = uieditfield(app.rf, 'numeric');
            app.NFM_FMT_RF.ValueDisplayFormat = '%.0f';
            app.NFM_FMT_RF.Value = 100;
            app.FMKHzEditFieldLabel = uilabel(app.rf);
            app.FMKHzEditFieldLabel.Text = 'FM调制频偏/KHz';
            app.FM_Freq_RF = uieditfield(app.rf, 'numeric');
            app.FM_Freq_RF.ValueDisplayFormat = '%.0f';
            app.FM_Freq_RF.Value = 125;
            app.AMLabel = uilabel(app.rf);
            app.AMLabel.Text = 'AM幅度系数/%';
            app.AM_Param_RF = uieditfield(app.rf, 'numeric');
            app.AM_Param_RF.Limits = [0 100];
            app.AM_Param_RF.ValueDisplayFormat = '%.0f';
            app.AM_Param_RF.Value = 50;
            app.READYButton_RF = uibutton(app.rf, 'push');
            app.READYButton_RF.ButtonPushedFcn = createCallbackFcn(app, @READYButton_RFPushed, true);
            app.READYButton_RF.Text = 'READY';
            app.ttl = uitab(app.TabGroup);
            app.ttl.Title = '电平类';
            app.RadarPanel = uipanel(app.ttl);
            app.RadarPanel.Title = '雷达类';
            app.DropDown_4Label = uilabel(app.RadarPanel);
            app.DropDown_4Label.Text = '通道设置';
            app.ChannelSet_Radar = uidropdown(app.RadarPanel);
            app.ChannelSet_Radar.Items = {'XS13', 'XS14', 'XS15', 'XS16'};
            app.ChannelSet_Radar.Value = 'XS13';
            app.DropDown_5Label = uilabel(app.RadarPanel);
            app.DropDown_5Label.Text = '信号样式';
            app.SignalStyle_Radar = uidropdown(app.RadarPanel);
            app.SignalStyle_Radar.Items = {'雷达脉冲信号', '雷达导前信号'};
            app.SignalStyle_Radar.Value = '雷达脉冲信号';
            app.DropDown_6Label = uilabel(app.RadarPanel);
            app.DropDown_6Label.Text = '通道开关';
            app.ChannelSw_Radar = uidropdown(app.RadarPanel);
            app.ChannelSw_Radar.Items = {'开', '关'};
            app.ChannelSw_Radar.Value = '开';
            app.usLabel = uilabel(app.RadarPanel);
            app.usLabel.Text = '导前脉宽/us';
            app.PW_Radar = uieditfield(app.RadarPanel, 'numeric');
            app.PW_Radar.Value = 10;
            app.usLabel_2 = uilabel(app.RadarPanel);
            app.usLabel_2.Text = '导后延时/us';
            app.DelayTime_Radar = uieditfield(app.RadarPanel, 'numeric');
            app.DelayTime_Radar.Value = 50;
            app.READYButton_Radar = uibutton(app.RadarPanel, 'push');
            app.READYButton_Radar.ButtonPushedFcn = createCallbackFcn(app, @READYButton_RadarPushed, true);
            app.READYButton_Radar.Text = 'READY';
            app.TTLPanel = uipanel(app.ttl);
            app.TTLPanel.Title = 'TTL';
            app.DropDown_7Label = uilabel(app.TTLPanel);
            app.DropDown_7Label.Text = '通道设置';
            app.ChannelSet_TTL = uidropdown(app.TTLPanel);
            app.ChannelSet_TTL.Items = {'XS17', 'XS18', 'XS19', 'XS20'};
            app.ChannelSet_TTL.Value = 'XS17';
            app.DropDown_8Label = uilabel(app.TTLPanel);
            app.DropDown_8Label.Text = '信号样式';
            app.SignalStyle_TTL = uidropdown(app.TTLPanel);
            app.SignalStyle_TTL.Items = {'TTL高低电平', '时序脉冲'};
            app.SignalStyle_TTL.Value = 'TTL高低电平';
            app.DropDown_9Label = uilabel(app.TTLPanel);
            app.DropDown_9Label.Text = '通道开关';
            app.ChannelSw_TTL = uidropdown(app.TTLPanel);
            app.ChannelSw_TTL.Items = {'开', '关'};
            app.ChannelSw_TTL.Value = '开';
            app.TTLEditFieldLabel = uilabel(app.TTLPanel);
            app.TTLEditFieldLabel.Text = 'TTL高低';
            app.TTLHL = uieditfield(app.TTLPanel, 'numeric');
            app.TTLHL.Limits = [0 1];
            app.TTLHL.ValueDisplayFormat = '%.0f';
            app.TTLHL.Value = 1;
            app.usLabel_3 = uilabel(app.TTLPanel);
            app.usLabel_3.Text = '时序脉宽/us';
            app.TTLPW = uieditfield(app.TTLPanel, 'numeric');
            app.TTLPW.Value = 50;
            app.usLabel_4 = uilabel(app.TTLPanel);
            app.usLabel_4.Text = '时序周期/us';
            app.TTLPeriod = uieditfield(app.TTLPanel, 'numeric');
            app.TTLPeriod.Value = 100;
            app.READYButton_TTL = uibutton(app.TTLPanel, 'push');
            app.READYButton_TTL.ButtonPushedFcn = createCallbackFcn(app, @READYButton_TTLPushed, true);
            app.READYButton_TTL.Text = 'READY';
            app.power = uitab(app.TabGroup);
            app.power.Title = '电源类';
            app.Label_5 = uilabel(app.power);
            app.Label_5.Text = '电压/V';
            app.Voltage_Power = uieditfield(app.power, 'numeric');
            app.Voltage_Power.Limits = [0 48];
            app.Voltage_Power.ValueDisplayFormat = '%.0f';
            app.Voltage_Power.Value = 12;
            app.AEditFieldLabel = uilabel(app.power);
            app.AEditFieldLabel.Text = '电流/A';
            app.Current_Power = uieditfield(app.power, 'numeric');
            app.Current_Power.Limits = [0 4];
            app.Current_Power.ValueDisplayFormat = '%.0f';
            app.Current_Power.Value = 2;
            app.Switch_XS21 = uiswitch(app.power, 'slider');
            app.READYButton_Power = uibutton(app.power, 'push');
            app.READYButton_Power.ButtonPushedFcn = createCallbackFcn(app, @READYButton_PowerPushed, true);
            app.READYButton_Power.Text = 'READY';
            app.DropDown_4Label_2 = uilabel(app.power);
            app.DropDown_4Label_2.Text = '通道设置';
            app.ChannelSet_Power = uidropdown(app.power);
            app.ChannelSet_Power.Items = {'XS01', 'XS02', 'XS03', 'XS04', 'XS05', 'XS06', 'XS07', 'XS08', 'XS09', 'XS10', 'XS11', 'XS12'};
            app.ChannelSet_Power.Value = 'XS01';
            app.led = uitab(app.TabGroup);
            app.led.Title = '指示灯';
            app.XS01_LED = uicheckbox(app.led);
            app.XS01_LED.Text = 'XS01';
            app.XS02_LED = uicheckbox(app.led);
            app.XS02_LED.Text = 'XS02';
            app.XS03_LED = uicheckbox(app.led);
            app.XS03_LED.Text = 'XS03';
            app.XS04_LED = uicheckbox(app.led);
            app.XS04_LED.Text = 'XS04';
            app.XS05_LED = uicheckbox(app.led);
            app.XS05_LED.Text = 'XS05';
            app.XS06_LED = uicheckbox(app.led);
            app.XS06_LED.Text = 'XS06';
            app.XS07_LED = uicheckbox(app.led);
            app.XS07_LED.Text = 'XS07';
            app.XS08_LED = uicheckbox(app.led);
            app.XS08_LED.Text = 'XS08';
            app.XS09_LED = uicheckbox(app.led);
            app.XS09_LED.Text = 'XS09';
            app.XS10_LED = uicheckbox(app.led);
            app.XS10_LED.Text = 'XS10';
            app.XS11_LED = uicheckbox(app.led);
            app.XS11_LED.Text = 'XS11';
            app.XS12_LED = uicheckbox(app.led);
            app.XS12_LED.Text = 'XS12';
            app.XS13_LED = uicheckbox(app.led);
            app.XS13_LED.Text = 'XS13';
            app.XS14_LED = uicheckbox(app.led);
            app.XS14_LED.Text = 'XS14';
            app.XS15_LED = uicheckbox(app.led);
            app.XS15_LED.Text = 'XS15';
            app.XS16_LED = uicheckbox(app.led);
            app.XS16_LED.Text = 'XS16';
            app.XS17_LED = uicheckbox(app.led);
            app.XS17_LED.Text = 'XS17';
            app.XS18_LED = uicheckbox(app.led);
            app.XS18_LED.Text = 'XS18';
            app.XS19_LED = uicheckbox(app.led);
            app.XS19_LED.Text = 'XS19';
            app.XS20_LED = uicheckbox(app.led);
            app.XS20_LED.Text = 'XS20';
            app.XS21_LED = uicheckbox(app.led);
            app.XS21_LED.Text = 'XS21';
            app.XS22_LED = uicheckbox(app.led);
            app.XS22_LED.Text = 'XS22';
            app.XS23_LED = uicheckbox(app.led);
            app.XS23_LED.Text = 'XS23';
            app.XS24_LED = uicheckbox(app.led);
            app.XS24_LED.Text = 'XS24';
            app.READYButton_LED = uibutton(app.led, 'push');
            app.READYButton_LED.ButtonPushedFcn = createCallbackFcn(app, @READYButton_LEDPushed, true);
            app.READYButton_LED.Text = 'READY';
            app.UDPTab = uitab(app.TabGroup);
            app.UDPTab.Title = 'UDP配置';
            app.IPIPEditField_2Label = uilabel(app.UDPTab);
            app.IPIPEditField_2Label.Text = '本地IP（上位机IP）';
            app.LOC_IP = uieditfield(app.UDPTab, 'text');
            app.LOC_IP.Value = '192.168.5.131';
            app.IPLabel = uilabel(app.UDPTab);
            app.IPLabel.Text = '远端IP（设备IP）';
            app.DIS_IP = uieditfield(app.UDPTab, 'text');
            app.DIS_IP.Value = '192.168.5.82';
            app.UDP_TESTButton = uibutton(app.UDPTab, 'push');
            app.UDP_TESTButton.ButtonPushedFcn = createCallbackFcn(app, @UDP_TESTButtonPushed, true);
            app.UDP_TESTButton.Text = 'UDP_TEST';
            app.Label_8 = uilabel(app.UDPTab);
            app.Label_8.Text = '本地端口';
            app.LOC_Port = uieditfield(app.UDPTab, 'numeric');
            app.LOC_Port.Limits = [0 65535];
            app.LOC_Port.ValueDisplayFormat = '%.0f';
            app.LOC_Port.Value = 8080;
            app.Label_9 = uilabel(app.UDPTab);
            app.Label_9.Text = '远端端口';
            app.DIS_Port = uieditfield(app.UDPTab, 'numeric');
            app.DIS_Port.Limits = [0 65535];
            app.DIS_Port.ValueDisplayFormat = '%.0f';
            app.DIS_Port.Value = 5010;
            app.Bind_Button = uibutton(app.UDPTab, 'state');
            app.Bind_Button.ValueChangedFcn = createCallbackFcn(app, @Bind_ButtonValueChanged, true);
            app.Bind_Button.Text = '绑定';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = udp_cmd_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end